classdef deeplabcut_analysis < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        objectdetectionparametersPanel  matlab.ui.container.Panel
        helpButton                      matlab.ui.control.Button
        thresholdEditField              matlab.ui.control.NumericEditField
        thresholdEditFieldLabel         matlab.ui.control.Label
        medfiltEditField                matlab.ui.control.NumericEditField
        medfiltEditField_2Label         matlab.ui.control.Label
        minsizeEditField                matlab.ui.control.NumericEditField
        minsizeEditFieldLabel           matlab.ui.control.Label
        bkgdEditField                   matlab.ui.control.NumericEditField
        bkgdEditFieldLabel              matlab.ui.control.Label
        hlEditField                     matlab.ui.control.NumericEditField
        hlEditFieldLabel                matlab.ui.control.Label
        StatusLabel                     matlab.ui.control.Label
        FilesanalyzedListBox            matlab.ui.control.ListBox
        FilesanalyzedListBoxLabel       matlab.ui.control.Label
        removeselectedButton            matlab.ui.control.Button
        deeplabcutanalysisLabel         matlab.ui.control.Label
        AddfilesButton                  matlab.ui.control.Button
        ExitButton                      matlab.ui.control.Button
        AnalyzeButton                   matlab.ui.control.Button
        FilestoanalyzeListBox           matlab.ui.control.ListBox
        FilestoanalyzeListBoxLabel      matlab.ui.control.Label
        AnalysisTypeDropDown            matlab.ui.control.DropDown
        AnalysisTypeDropDownLabel       matlab.ui.control.Label
    end

    
    properties (Access = public)
        ymaze_files
        ymaze_paths
        ymaze_files_analyzed
        
        open_files
        open_paths
        open_files_analyzed
        
        object_files
        object_paths
        object_files_analyzed
    end
    
    methods (Access = private)
        
        function update_files_list(app)
            app.FilestoanalyzeListBox.Items = {};
            app.FilesanalyzedListBox.Items = {};
            
            switch app.AnalysisTypeDropDown.Value
                case 'Y-Maze'
                    app.objectdetectionparametersPanel.Visible = 0;
                    num_files = length(app.ymaze_files);
                    remove_inds = false(1,num_files);
                    if num_files==1
                        if isempty(strfind(app.ymaze_files{1},'YMaze')) %remove file if it isn't a YMaze DLC file
                            remove_inds = true;
                        end
                    elseif num_files>1
                        for i = length(app.ymaze_files):-1:1
                            if any(strcmp(app.ymaze_files(1:i-1),app.ymaze_files(i))) %remove duplicates
                                remove_inds(i) = true;
                            elseif isempty(strfind(app.ymaze_files{i},'YMaze')) %remove files that aren't YMaze DLC files
                                remove_inds(i) = true;
                            end
                        end
                    end
                    app.ymaze_files(remove_inds) = [];
                    app.ymaze_paths(remove_inds) = [];
                    app.ymaze_files_analyzed(remove_inds) = [];
                    if isempty(app.ymaze_files)
                        app.FilestoanalyzeListBox.Items = {'no files added'};
                        app.FilestoanalyzeListBox.Enable = 0;
                    else
                        app.FilestoanalyzeListBox.Enable = 1;
                        for i = 1:length(app.ymaze_files)
                            if app.ymaze_files_analyzed(i)==0
                                app.FilestoanalyzeListBox.Items = [app.FilestoanalyzeListBox.Items, app.ymaze_files(i)];
                            else
                                app.FilesanalyzedListBox.Items = [app.FilesanalyzedListBox.Items, app.ymaze_files(i)];
                            end
                        end
                    end
                        
                case 'Open field'
                    app.objectdetectionparametersPanel.Visible = 0;
                    num_files = length(app.open_files);
                    remove_inds = false(1,num_files);
                    if num_files==1
                        if isempty(strfind(app.open_files{1},'BehaviorBox')) %remove file if it isn't a BehaviorBox DLC file
                            remove_inds = true;
                        end
                    elseif num_files>1
                        for i = length(app.open_files):-1:1
                            if any(strcmp(app.open_files(1:i-1),app.open_files(i))) %remove duplicates
                                remove_inds(i) = true;
                            elseif isempty(strfind(app.open_files{i},'BehaviorBox')) %remove files that aren't BehaviorBox DLC files
                                remove_inds(i) = true;
                            end
                        end
                    end
                    app.open_files(remove_inds) = [];
                    app.open_paths(remove_inds) = [];
                    app.open_files_analyzed(remove_inds) = [];
                    if isempty(app.open_files)
                        app.FilestoanalyzeListBox.Items = {'no files added'};
                        app.FilestoanalyzeListBox.Enable = 0;
                    else
                        app.FilestoanalyzeListBox.Enable = 1;
                        for i = 1:length(app.open_files)
                            if app.open_files_analyzed(i)==0
                                app.FilestoanalyzeListBox.Items = [app.FilestoanalyzeListBox.Items, app.open_files(i)];
                            else
                                app.FilesanalyzedListBox.Items = [app.FilesanalyzedListBox.Items, app.open_files(i)];
                            end
                        end
                    end
                    
                case 'Object interaction'
                    app.objectdetectionparametersPanel.Visible = 1;
                    num_files = length(app.object_files);
                    remove_inds = false(1,num_files);
                    if num_files==1
                        if isempty(strfind(app.object_files{1},'BehaviorBox')) %remove file if it isn't a BehaviorBox DLC file
                            remove_inds = true;
                        end
                    elseif num_files>1
                        for i = length(app.object_files):-1:1
                            if any(strcmp(app.object_files(1:i-1),app.object_files(i))) %remove duplicates
                                remove_inds(i) = true;
                            elseif isempty(strfind(app.object_files{i},'BehaviorBox')) %remove files that aren't BehaviorBox DLC files
                                remove_inds(i) = true;
                            end
                        end
                    end
                    app.object_files(remove_inds) = [];
                    app.object_paths(remove_inds) = [];
                    app.object_files_analyzed(remove_inds) = [];
                    if isempty(app.object_files)
                        app.FilestoanalyzeListBox.Items = {'no files added'};
                        app.FilestoanalyzeListBox.Enable = 0;
                    else
                        app.FilestoanalyzeListBox.Enable = 1;
                        for i = 1:length(app.object_files)
                            if app.object_files_analyzed(i)==0
                                app.FilestoanalyzeListBox.Items = [app.FilestoanalyzeListBox.Items, app.object_files(i)];
                            else
                                app.FilesanalyzedListBox.Items = [app.FilesanalyzedListBox.Items, app.object_files(i)];
                            end
                        end
                    end
            end
            figure(app.UIFigure)
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            delete(app)
        end

        % Button pushed function: AddfilesButton
        function AddfilesButtonPushed(app, event)
            switch app.AnalysisTypeDropDown.Value
                case 'Y-Maze'
                    [new_files,new_paths] = uigetfile('*.h5',...
                        'Select One or More YMaze h5 Files', ...
                        'MultiSelect', 'on');
                    if ~isempty(new_files)
                        if iscell(new_files) %new_files is a cell array; turn new_paths from string to cell array
                            new_paths = repmat({new_paths},[1 length(new_files)]);
                        else %both are strings; make them into cells
                            new_files = {new_files};
                            new_paths = {new_paths};
                        end 
                        app.ymaze_files = [app.ymaze_files new_files];
                        app.ymaze_paths = [app.ymaze_paths new_paths];
                        app.ymaze_files_analyzed = [app.ymaze_files_analyzed zeros(1,length(new_files))];
                    end
                    
                case 'Open field'
                    [new_files,new_paths] = uigetfile('*.h5',...
                        'Select One or More BehaviorBox h5 Files', ...
                        'MultiSelect', 'on');
                    if ~isempty(new_files)
                        if iscell(new_files) %new_files is a cell array; turn new_paths from string to cell array
                            new_paths = repmat({new_paths},[1 length(new_files)]);
                        else %both are strings; make them into cells
                            new_files = {new_files};
                            new_paths = {new_paths};
                        end 
                        app.open_files = [app.open_files new_files];
                        app.open_paths = [app.open_paths new_paths];
                        app.open_files_analyzed = [app.open_files_analyzed zeros(1,length(new_files))];
                    end
                    
                case 'Object interaction'
                    [new_files,new_paths] = uigetfile('*.h5',...
                        'Select One or More BehaviorBox h5 Files', ...
                        'MultiSelect', 'on');
                    if ~isempty(new_files)
                        if iscell(new_files) %new_files is a cell array; turn new_paths from string to cell array
                            new_paths = repmat({new_paths},[1 length(new_files)]);
                        else %both are strings -- make them into cells
                            new_files = {new_files};
                            new_paths = {new_paths};
                        end 
                        app.object_files = [app.object_files new_files];
                        app.object_paths = [app.object_paths new_paths];
                        app.object_files_analyzed = [app.object_files_analyzed zeros(1,length(new_files))];
                    end
            end
            
            update_files_list(app)
   
        end

        % Value changed function: AnalysisTypeDropDown
        function AnalysisTypeDropDownValueChanged(app, event)
            update_files_list(app)
        end

        % Button pushed function: removeselectedButton
        function removeselectedButtonPushed(app, event)
            for i = 1:length(app.FilestoanalyzeListBox.Value)
                curval = app.FilestoanalyzeListBox.Value(i);
                switch app.AnalysisTypeDropDown.Value
                    case 'Y-Maze'
                        ind_to_remove = find(strcmp(app.ymaze_files,curval));
                        app.ymaze_files(ind_to_remove) = [];
                        app.ymaze_paths(ind_to_remove) = [];
                        app.ymaze_files_analyzed(ind_to_remove) = [];
                        
                    case 'Open field'
                        ind_to_remove = find(strcmp(app.open_files,curval));
                        app.open_files(ind_to_remove) = [];
                        app.open_paths(ind_to_remove) = [];
                        app.object_files_analyzed(ind_to_remove) = [];
                        
                    case 'Object interaction'
                        ind_to_remove = find(strcmp(app.object_files,curval));
                        app.object_files(ind_to_remove) = [];
                        app.object_paths(ind_to_remove) = [];
                        app.object_files_analyzed(ind_to_remove) = [];
                end
            end
            
            update_files_list(app)
        end

        % Button pushed function: AnalyzeButton
        function AnalyzeButtonPushed(app, event)
            app.StatusLabel.Text = 'Analyzing...';
            pause(0.01)
            switch app.AnalysisTypeDropDown.Value
                case 'Y-Maze'
                    for i = 1:length(app.ymaze_files)
                        if app.ymaze_files_analyzed(i)==0
                            try
                                analyze_ymaze(fullfile(app.ymaze_paths{i},app.ymaze_files{i}));
                                app.ymaze_files_analyzed(i) = 1;
                                update_files_list(app);
                                pause(0.01)
                            catch
                                app.ymaze_files_analyzed(i) = 0;
                                app.StatusLabel.Text = ['error during analysis of ' app.ymaze_files{i}];
                                pause(2)
                            end
                        end
                    end
                case 'Open field'
                    for i = 1:length(app.open_files)
                        if app.open_files_analyzed(i)==0
                            try
                                analyze_openfield(fullfile(app.open_paths{i},app.open_files{i}));
                                app.open_files_analyzed(i) = 1;
                                update_files_list(app);
                                pause(0.01)
                            catch
                                app.open_files_analyzed(i) = 0;
                                app.StatusLabel.Text = ['error during analysis of ' app.open_files{i}];
                                pause(2)
                            end
                        end
                    end
                case 'Object interaction'
                    app.StatusLabel.Text = 'Analyzing... (this may take a few minutes per file)';     
                    pause(0.01)
                    for i = 1:length(app.object_files)
                        if app.object_files_analyzed(i)==0
                            try
                                param.hl_ratio = app.hlEditField.Value;
                                param.bkgd_fraction = app.bkgdEditField.Value;
                                param.zscore_threshold = app.thresholdEditField.Value;
                                param.min_obj_size = app.minsizeEditField.Value;
                                param.med_filt = app.medfiltEditField.Value;
                                analyze_objectinteraction(fullfile(app.object_paths{i},app.object_files{i}),param);
                                app.object_files_analyzed(i) = 1;
                                update_files_list(app);
                                pause(0.01)
                            catch
                                app.object_files_analyzed(i) = 0;
                                app.StatusLabel.Text = ['error during analysis of ' app.object_files{i}];
                                pause(2)
                            end
                        end
                    end
            end
            app.StatusLabel.Text = ''; 
        end

        % Button pushed function: helpButton
        function helpButtonPushed(app, event)
            msgbox({'Object detection parameters can be adjusted if object outlines are ';...
                'inaccurate. Use "...DetectionDetails.fig" and the notes below to optimize ';...
                'these parameters for your video.';...
                '';...
                '';...
                'BKGD: the fraction of pixels inside the box which aren''t object pixels. '; ...
                '  For small objects, the bkgd can be larger (e.g. 0.9, 0.95), and vice '; ...
                '  versa. Better to underestimate this than overstimate it (i.e. use ';...
                '  smaller bkgd values). If any object pixels are colored white in panel 3,';...
                '  use a smaller bkgd value.'; ...
                '';...
                'H/L: this value specifies the fraction of hue (vs level) to use for ';...
                '  detecting objects over the background. Values closer to 1 will use the '; ...
                '  hue more to detect objects (good for bright, not-red objects, with ';...
                '  higher quality detection in panel 4), while values closer to 0 will use'; ...
                '  the darker-levels more (good for dark objects, with higher quality ';...
                '  detection in panel 5, but bad for shadows).';...
                '';...
                'MED FILT: the number of pixels to median filter the combined z-score image ';...
                '  in panel 6. Use larger values if background noise is too large; smaller '; ...
                '  values if actual object pixels are being excluded.'; ...
                '';...
                'THRESHOLD: the z-score threshold used to convert the combined z-score '; ...
                '  image in panel 6, to the objects mask in panel 7. Try a larger threshold ';...
                '  if too much noise is being included; smaller if object pixels are being ';...
                '  excluded.';...
                ''; ...
                'MIN SIZE: the minimum size (in pixels) to consider connected components to';...
                '  be objects. Use larger values to prevent small, noisy areas from being ';...
                '  labelled as objects in panel 8; smaller values if a real object is being ';...
                '  excluded in panel 8 (objects may also be excluded by a too-high '; ...
                '  threshold).'; ...
                ''},'about object detection parameters');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.AnalysisTypeDropDownLabel = uilabel(app.UIFigure);
            app.AnalysisTypeDropDownLabel.Text = 'Analysis Type';
            app.AnalysisTypeDropDown = uidropdown(app.UIFigure);
            app.AnalysisTypeDropDown.Items = {'Y-Maze', 'Open field', 'Object interaction'};
            app.AnalysisTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @AnalysisTypeDropDownValueChanged, true);
            app.AnalysisTypeDropDown.Value = 'Y-Maze';
            app.FilestoanalyzeListBoxLabel = uilabel(app.UIFigure);
            app.FilestoanalyzeListBoxLabel.Text = 'Files to analyze';
            app.FilestoanalyzeListBox = uilistbox(app.UIFigure);
            app.FilestoanalyzeListBox.Items = {'no files added'};
            app.FilestoanalyzeListBox.Multiselect = 'on';
            app.FilestoanalyzeListBox.Enable = 'off';
            app.FilestoanalyzeListBox.Value = {};
            app.AnalyzeButton = uibutton(app.UIFigure, 'push');
            app.AnalyzeButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeButtonPushed, true);
            app.AnalyzeButton.Text = 'Analyze';
            app.ExitButton = uibutton(app.UIFigure, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            app.AddfilesButton = uibutton(app.UIFigure, 'push');
            app.AddfilesButton.ButtonPushedFcn = createCallbackFcn(app, @AddfilesButtonPushed, true);
            app.AddfilesButton.Text = 'Add files';
            app.deeplabcutanalysisLabel = uilabel(app.UIFigure);
            app.deeplabcutanalysisLabel.Text = 'deeplabcut analysis';
            app.removeselectedButton = uibutton(app.UIFigure, 'push');
            app.removeselectedButton.ButtonPushedFcn = createCallbackFcn(app, @removeselectedButtonPushed, true);
            app.removeselectedButton.Text = {'remove'; 'selected'};
            app.FilesanalyzedListBoxLabel = uilabel(app.UIFigure);
            app.FilesanalyzedListBoxLabel.Text = 'Files analyzed';
            app.FilesanalyzedListBox = uilistbox(app.UIFigure);
            app.FilesanalyzedListBox.Items = {};
            app.FilesanalyzedListBox.Enable = 'off';
            app.FilesanalyzedListBox.Value = {};
            app.StatusLabel = uilabel(app.UIFigure);
            app.StatusLabel.Text = '';
            app.objectdetectionparametersPanel = uipanel(app.UIFigure);
            app.objectdetectionparametersPanel.Title = 'object detection parameters';
            app.objectdetectionparametersPanel.Visible = 'off';
            app.hlEditFieldLabel = uilabel(app.objectdetectionparametersPanel);
            app.hlEditFieldLabel.Text = 'h/l';
            app.hlEditField = uieditfield(app.objectdetectionparametersPanel, 'numeric');
            app.hlEditField.Limits = [0 1];
            app.hlEditField.Value = 0.3;
            app.bkgdEditFieldLabel = uilabel(app.objectdetectionparametersPanel);
            app.bkgdEditFieldLabel.Text = 'bkgd';
            app.bkgdEditField = uieditfield(app.objectdetectionparametersPanel, 'numeric');
            app.bkgdEditField.Limits = [0 1];
            app.bkgdEditField.Value = 0.85;
            app.minsizeEditFieldLabel = uilabel(app.objectdetectionparametersPanel);
            app.minsizeEditFieldLabel.Text = 'min size';
            app.minsizeEditField = uieditfield(app.objectdetectionparametersPanel, 'numeric');
            app.minsizeEditField.Limits = [1 Inf];
            app.minsizeEditField.Value = 1000;
            app.medfiltEditField_2Label = uilabel(app.objectdetectionparametersPanel);
            app.medfiltEditField_2Label.Text = 'med filt';
            app.medfiltEditField = uieditfield(app.objectdetectionparametersPanel, 'numeric');
            app.medfiltEditField.Limits = [1 Inf];
            app.medfiltEditField.Value = 20;
            app.thresholdEditFieldLabel = uilabel(app.objectdetectionparametersPanel);
            app.thresholdEditFieldLabel.Text = 'threshold';
            app.thresholdEditField = uieditfield(app.objectdetectionparametersPanel, 'numeric');
            app.thresholdEditField.Limits = [0 Inf];
            app.thresholdEditField.Value = 3;
            app.helpButton = uibutton(app.objectdetectionparametersPanel, 'push');
            app.helpButton.ButtonPushedFcn = createCallbackFcn(app, @helpButtonPushed, true);
            app.helpButton.Text = 'help';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = deeplabcut_analysis

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end